classdef AnnotationGui_v11_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        AnnotatingPanel                matlab.ui.container.Panel
        MainMenuPanel                  matlab.ui.container.Panel
        FileAlreadyInProgressWarning   matlab.ui.control.Label
        TypeoffiletoaccessButtonGroup  matlab.ui.container.ButtonGroup
        FinishedButton                 matlab.ui.control.RadioButton
        InProgressButton               matlab.ui.control.RadioButton
        StartingNewButton              matlab.ui.control.RadioButton
        SamplingRate                   matlab.ui.control.NumericEditField
        WhatisthesamplingrateHzLabel   matlab.ui.control.Label
        FileNotFoundErrorLabel         matlab.ui.control.Label
        SubjectEditField               matlab.ui.control.NumericEditField
        SubjectLabel_2                 matlab.ui.control.Label
        AnnotatorFirstNameEditField    matlab.ui.control.EditField
        AnnotatorFirstNameEditFieldLabel  matlab.ui.control.Label
        StartAnnotatingButton          matlab.ui.control.Button
        ShiftedCheckBox                matlab.ui.control.CheckBox
        Clear_Annotations              matlab.ui.control.Button
        XEditField                     matlab.ui.control.NumericEditField
        XEditFieldLabel                matlab.ui.control.Label
        ButtonGroup_3                  matlab.ui.container.ButtonGroup
        GoodButton                     matlab.ui.control.ToggleButton
        AverageButton                  matlab.ui.control.ToggleButton
        BadButton                      matlab.ui.control.ToggleButton
        NoRatingButton_2               matlab.ui.control.ToggleButton
        LowestUnannotatedBeatLabel     matlab.ui.control.Label
        AC2                            matlab.ui.control.NumericEditField
        AC1                            matlab.ui.control.NumericEditField
        ShowentireHeatMapButton        matlab.ui.control.Button
        ACUB                           matlab.ui.control.Label
        ACLB                           matlab.ui.control.Label
        AOUB                           matlab.ui.control.Label
        AOLB                           matlab.ui.control.Label
        HeatmapBoundApproximationsLabel  matlab.ui.control.Label
        AO2                            matlab.ui.control.NumericEditField
        AO1                            matlab.ui.control.NumericEditField
        JumptoButton                   matlab.ui.control.Button
        JumpTo                         matlab.ui.control.NumericEditField
        AnnotatorLabel                 matlab.ui.control.Label
        BeatLabel                      matlab.ui.control.Label
        SubjectLabel                   matlab.ui.control.Label
        Slider                         matlab.ui.control.Slider
        SliderLabel                    matlab.ui.control.Label
        Previous20Button               matlab.ui.control.Button
        Next20Button                   matlab.ui.control.Button
        NextSaveButton                 matlab.ui.control.Button
        PreviousButton                 matlab.ui.control.Button
        ButtonGroup_2                  matlab.ui.container.ButtonGroup
        BacktomenuButton               matlab.ui.control.Button
        MO_extrema                     matlab.ui.control.Switch
        MOPoint                        matlab.ui.control.NumericEditField
        MOButton                       matlab.ui.control.RadioButton
        ACv_extrema                    matlab.ui.control.Switch
        ACvPoint                       matlab.ui.control.NumericEditField
        ACvButton                      matlab.ui.control.RadioButton
        AO_extrema                     matlab.ui.control.Switch
        AC_extrema                     matlab.ui.control.Switch
        ACPoint                        matlab.ui.control.NumericEditField
        AOPoint                        matlab.ui.control.NumericEditField
        ACButton                       matlab.ui.control.RadioButton
        AOButton                       matlab.ui.control.RadioButton
        HeatMap                        matlab.ui.control.UIAxes
        LastXBeats                     matlab.ui.control.UIAxes
        ECG                            matlab.ui.control.UIAxes
        SCG                            matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        annotator % Name of the current annotator
        BeatIndex = 0 % Index of the current beat being looked at
        scgBeats % Data for 1000 beats loaded from a given mat file
        ecgBeats
        ecgBeatsExist = 0
        currentSubjectID % Current subject being annotated
        fileEnding = '.mat' % Ending of the file being searched for. (resuming work or starting fresh)
        AOMaxHandle % Handle for the local maxima point
        ACMaxHandle % Handle for the local maxima point
        ACvMaxHandle
        MOMaxHandle
        samplingRateValueHz % Inputted sampling rate from the USER
        lengthOfXAxis % Calculated using scgBeats.length / ((samplingRateValue*1000)/1000)
        numColumns
        numRows
        HeatmapLineHandles
        SCGVertHeatMapLines
        AntiSpam
        pickingUp = 0;
        revisitingFinished = 0;
        notFinished;
        mainMenuShowing = 1;
        lastKBeatsValue = 0;
        CtrlPressed;

        % Arrays used to save data
        scgScores % Array that will hold the scgScores for all of the beats (index will line up with BeatIndex)
        scgRatings % NEW RATING SYSTEM (-1 means no rating, 0 means bad, 1 means average, and 2 means good)
        HeatmapAOLowerBound
        AOLowerBounds % Array that will hold AO Lower Bounds
        HeatmapAOUpperBound
        AOUpperBounds % Array that will hold AO Upper Bounds
        AOMaxPoints % Array that will hold AO Max Points
        ACLowerBounds
        HeatmapACLowerBound
        ACUpperBounds
        HeatmapACUpperBound
        ACMaxPoints
        lowestUnannotatedBeatIndex = 1
        ACvPoints
        MOPoints
        ShiftedBeats


    end
    
    methods (Access = private)
        
        function loadData(app)
            
            if (~app.pickingUp && ~app.revisitingFinished)
                data = load(['S', num2str(app.currentSubjectID), '_scg_', app.annotator, ...
                app.fileEnding]);
            else 
                if (app.pickingUp && ~app.revisitingFinished)
                    data = load(['INPROGRESS_S', num2str(app.currentSubjectID), '_scg_', app.annotator, ...
                    app.fileEnding]);
                elseif (~app.pickingUp && app.revisitingFinished)
                    data = load(['FINISHED_S', num2str(app.currentSubjectID), '_scg_', app.annotator, ...
                    app.fileEnding]);
                end
                fieldsToCheck = { ...
                    'ACMaxPoints', 'AOMaxPoints', 'ACvPoints', 'MOPoints', ...
                    'predictedACLowerBound', 'predictedACUpperBound', ...
                    'predictedAOLowerBound', 'predictedAOUpperBound', ...
                    'ratings', 'lowestUnannotatedBeatIndex'};

                % Map the fields to their corresponding app properties
                appProperties = { ...
                    'ACMaxPoints', 'AOMaxPoints', 'ACvPoints', 'MOPoints', ...
                    'HeatmapACLowerBound', 'HeatmapACUpperBound', ...
                    'HeatmapAOLowerBound', 'HeatmapAOUpperBound', ...
                    'scgRatings', 'lowestUnannotatedBeatIndex'};

                % Loop through the fields and assign the values to app properties if they exist
                for i = 1:length(fieldsToCheck)
                    if isfield(data, fieldsToCheck{i})
                        app.(appProperties{i}) = data.(fieldsToCheck{i});
                    end
                end
            end

            if isfield(data, 'scgBeats')
                app.scgBeats = data.scgBeats;
            else
                error('The variable scgBeats is not found in the mat file.');
            end
            if isfield(data, 'ecgBeats')
                app.ecgBeats = data.ecgBeats;
                app.ecgBeatsExist = 1;
            else
                disp('The variable ecgBeats is not found in the mat file.');
            end
            if isfield(data, 'ShiftedBeats')
                app.ShiftedBeats = data.ShiftedBeats;
            else
                app.ShiftedBeats = zeros(1, size(app.scgBeats, 1));
                disp('The variable ShiftedBeats is not found in the mat file. A new array was created full of 0s.');
            end

        end

        function checkAndEnableStartButton(app)
            % Check if the SubjectEditField and AnnotatorNameEditField are valid
            subjectValid = ~isempty(app.SubjectEditField.Value);
            annotatorValid = ~isempty(app.AnnotatorFirstNameEditField.Value);
            newFileExists = isfile(['S', num2str(app.SubjectEditField.Value), '_scg_', app.annotator, app.fileEnding]);
            IPFileExists = isfile(['INPROGRESS_S', num2str(app.SubjectEditField.Value), '_scg_', app.annotator, app.fileEnding]);
            FFileExists = isfile(['FINISHED_S', num2str(app.SubjectEditField.Value), '_scg_', app.annotator, app.fileEnding])

            if subjectValid && annotatorValid && newFileExists && ~app.pickingUp && ~app.revisitingFinished
                app.StartAnnotatingButton.Enable = 'on';
                app.FileNotFoundErrorLabel.Text = "";
            elseif subjectValid && annotatorValid && IPFileExists && app.pickingUp && ~app.revisitingFinished
                app.StartAnnotatingButton.Enable = 'on';
                app.FileNotFoundErrorLabel.Text = "";
            elseif subjectValid && annotatorValid && FFileExists && ~app.pickingUp && app.revisitingFinished
                app.StartAnnotatingButton.Enable = 'on';
                app.FileNotFoundErrorLabel.Text = "";
            else
                app.StartAnnotatingButton.Enable = 'off';
                if (app.pickingUp)
                    app.FileNotFoundErrorLabel.Text = "A file does not exist with the " + ...
                        "format: " + newline + "INPROGRESS_S<ID>_scg_<annotator name>.mat";
                elseif (app.revisitingFinished)
                    app.FileNotFoundErrorLabel.Text = "A file does not exist with the " + ...
                        "format: " + newline + "FINISHED_S<ID>_scg_<annotator name>.mat";
                else
                    app.FileNotFoundErrorLabel.Text = "A file does not exist with the " + ...
                        "format: " + newline + "S<ID>_scg_<annotator name>.mat";
                end
            end
        end

        
        % FINISH SAVE DATA // to be implemented
        function saveData(app)
            scgBeats = app.scgBeats;
            ecgBeats = app.ecgBeats;
            annotator = app.annotator;
            subjectID = num2str(app.currentSubjectID);
            samplingRate = app.samplingRateValueHz;
            
            %scores = app.scgScores;
            ratings = app.scgRatings;
            %AOLowerBounds = app.AOLowerBounds;
            %AOUpperBounds = app.AOUpperBounds;
            AOMaxPoints = app.AOMaxPoints;
            %ACLowerBounds = app.ACLowerBounds;
            %ACUpperBounds = app.ACUpperBounds;
            ACMaxPoints = app.ACMaxPoints;
            ACvPoints = app.ACvPoints;
            MOPoints = app.MOPoints;
            ShiftedBeats = app.ShiftedBeats;

            predictedAOLowerBound = str2double(app.AOLB.Text);
            predictedAOUpperBound = str2double(app.AOUB.Text);
            predictedACLowerBound = str2double(app.ACLB.Text);
            predictedACUpperBound = str2double(app.ACUB.Text);
            lowestUnannotatedBeatIndex = app.lowestUnannotatedBeatIndex;

            if (app.notFinished == 1)
                if isfile(['FINISHED_S' subjectID '_scg_' app.annotator '.mat'])
                    delete(['FINISHED_S' subjectID '_scg_' app.annotator '.mat']);
                    disp([['FINISHED_S' subjectID '_scg_' app.annotator '.mat'] ' has been deleted.']);
                end
                save(['INPROGRESS_S' subjectID '_scg_' app.annotator '.mat'], 'scgBeats', 'ecgBeats' , "annotator", "subjectID", "samplingRate", "ratings", ...
                "AOMaxPoints", "ACMaxPoints", "ACvPoints", "MOPoints", "ShiftedBeats", "predictedAOLowerBound", "predictedAOUpperBound", "predictedACLowerBound", "predictedACUpperBound", "lowestUnannotatedBeatIndex");
                disp(['Data saved successfully to ' ['INPROGRESS_S' subjectID '_scg_' app.annotator '.mat']]);
            else
                if isfile(['INPROGRESS_S' subjectID '_scg_' app.annotator '.mat'])
                    delete(['INPROGRESS_S' subjectID '_scg_' app.annotator '.mat']);
                    disp([['INPROGRESS_S' subjectID '_scg_' app.annotator '.mat'] ' has been deleted.']);
                end
                save(['FINISHED_S' subjectID '_scg_' app.annotator '.mat'], 'scgBeats', 'ecgBeats' , "annotator", "subjectID", "samplingRate", "ratings", ...
                "AOMaxPoints", "ACMaxPoints", "ACvPoints", "MOPoints","ShiftedBeats", "predictedAOLowerBound", "predictedAOUpperBound", "predictedACLowerBound", "predictedACUpperBound", "lowestUnannotatedBeatIndex");
                disp(['Data saved successfully to ' ['FINISHED_S' subjectID '_scg_' app.annotator '.mat']]);
            end

            
        end


        %saveInstance does not save to a mat file. Instead, it saves all
        %of the current annotations into a bunch of arrays. All of these
        %arrays will later be saved to a mat file via the saveData()
        %method.
        function saveInstance(app)
            % Get the currently selected button from the button group
            if (app.BadButton.Value)
                app.scgRatings(app.BeatIndex) = 0;
            elseif (app.AverageButton.Value)
                app.scgRatings(app.BeatIndex) = 1;
            elseif (app.GoodButton.Value)
                app.scgRatings(app.BeatIndex) = 2;
            else
                app.scgRatings(app.BeatIndex) = -1;
            end
            % Update other properties as needed
            app.AOLowerBounds(app.BeatIndex) = app.AO1.Value;
            app.AOUpperBounds(app.BeatIndex) = app.AO2.Value;
            app.AOMaxPoints(app.BeatIndex) = app.AOPoint.Value;
            app.ACLowerBounds(app.BeatIndex) = app.AC1.Value;
            app.ACUpperBounds(app.BeatIndex) = app.AC2.Value;
            app.ACMaxPoints(app.BeatIndex) = app.ACPoint.Value;
            app.ACvPoints(app.BeatIndex) = app.ACvPoint.Value;
            app.MOPoints(app.BeatIndex) = app.MOPoint.Value
            app.ShiftedBeats(app.BeatIndex) = app.ShiftedCheckBox.Value
        end

        
        % This method will be called after every next or previous, to check
        % if annotations already exist for that beat, and to draw them onto
        % the plot.
        % DW GUYS I GOT THIS ONE SMURFED
        function drawExistingAnnotations(app, plottedLine)
            %getting plot data
            xData = get(plottedLine, 'XData');
            yData = get(plottedLine, 'YData');

            %instantiating AO intervals and points if they exist
            %make sure to tag as AO
            lineTag = 'AO';
            lineColor = 'r';
            %if (app.AOLowerBounds(app.BeatIndex) ~= 0)
            %    vertline0 = xline(app.SCG, app.AOLowerBounds(app.BeatIndex), 'Color', lineColor, 'Tag', lineTag, 'HitTest', 'off');
            %end
            %if (app.AOUpperBounds(app.BeatIndex) ~= 0) 
            %    vertline1 = xline(app.SCG, app.AOUpperBounds(app.BeatIndex), 'Color', lineColor, 'Tag', lineTag, 'HitTest', 'off');
            %end
            if (app.AOMaxPoints(app.BeatIndex) ~= 0)
                %getting x extrema and y extrema point from the array and
                %plotted line
                Xextrema = app.AOMaxPoints(app.BeatIndex);

                %3rd vertical line to match up with implementation for the
                %scg on click listener
                vertline2 = xline(app.SCG, Xextrema, 'Color', lineColor, 'Tag', lineTag, 'HitTest', 'off');
                vertline2.Visible = 'off';
                
                %use the current plotted data to find the y value of the
                %max point
                [~, idx] = min(abs(xData - Xextrema));
                Yextrema = yData(idx);

                %hold the plot so it doesnt get overriden by the
                %AOMaxhandle
                hold(app.SCG, 'on');
                app.AOMaxHandle = plot(app.SCG, Xextrema, Yextrema, 'ro', 'MarkerSize', 10, 'LineWidth', 2, 'HitTest', 'off');
                hold(app.SCG, 'off');     
            end
            
            %same thing except for AC
            lineTag = 'AC';
            lineColor = 'b';
            %if (app.ACLowerBounds(app.BeatIndex) ~= 0)
            %    vertline3 = xline(app.SCG, app.ACLowerBounds(app.BeatIndex), 'Color', lineColor, 'Tag', lineTag, 'HitTest', 'off');
            %end
            %if (app.ACUpperBounds(app.BeatIndex) ~= 0)
            %    vertline4 = xline(app.SCG, app.ACUpperBounds(app.BeatIndex), 'Color', lineColor, 'Tag', lineTag, 'HitTest', 'off');
            %end
            if (app.ACMaxPoints(app.BeatIndex) ~= 0) 
                Xextrema = app.ACMaxPoints(app.BeatIndex);

                vertline5 = xline(app.SCG, Xextrema, 'Color', lineColor, 'Tag', lineTag, 'HitTest', 'off');
                vertline5.Visible = 'off';

                [~, idx] = min(abs(xData - Xextrema));
                Yextrema = yData(idx);
                hold(app.SCG, 'on');
                app.ACMaxHandle = plot(app.SCG, Xextrema, Yextrema, 'bo', 'MarkerSize', 10, 'LineWidth', 2, 'HitTest', 'off');
                hold(app.SCG, 'off');
            end

            lineTag = 'ACv'
            linecolor = 'm'
            if (app.ACvPoints(app.BeatIndex) ~= 0) 
                Xextrema = app.ACvPoints(app.BeatIndex);

                vertline6 = xline(app.SCG, Xextrema, 'Color', lineColor, 'Tag', lineTag, 'HitTest', 'off');
                vertline6.Visible = 'off';

                [~, idx] = min(abs(xData - Xextrema));
                Yextrema = yData(idx);
                hold(app.SCG, 'on');
                app.ACvMaxHandle = plot(app.SCG, Xextrema, Yextrema, 'co', 'MarkerSize', 10, 'LineWidth', 2, 'HitTest', 'off');
                hold(app.SCG, 'off');
            end

            lineTag = 'MO'
            linecolor = 'g'
            if (app.MOPoints(app.BeatIndex) ~= 0) 
                Xextrema = app.MOPoints(app.BeatIndex);

                vertline7 = xline(app.SCG, Xextrema, 'Color', lineColor, 'Tag', lineTag, 'HitTest', 'off');
                vertline7.Visible = 'off';

                [~, idx] = min(abs(xData - Xextrema));
                Yextrema = yData(idx);
                hold(app.SCG, 'on');
                app.MOMaxHandle = plot(app.SCG, Xextrema, Yextrema, 'go', 'MarkerSize', 10, 'LineWidth', 2, 'HitTest', 'off');
                hold(app.SCG, 'off');
            end

            drawnow
        end

        % This function will refill all editable fields with values that
        % could have been already inputted previously
        function refillFields(app)
            %app.AO1.Value = app.AOLowerBounds(app.BeatIndex);
            %app.AO2.Value = app.AOUpperBounds(app.BeatIndex);
            app.AOPoint.Value = app.AOMaxPoints(app.BeatIndex);

            %app.AC1.Value = app.ACLowerBounds(app.BeatIndex);
            %app.AC2.Value = app.ACUpperBounds(app.BeatIndex);
            app.ACPoint.Value = app.ACMaxPoints(app.BeatIndex);

            app.ACvPoint.Value = app.ACvPoints(app.BeatIndex);
            app.MOPoint.Value = app.MOPoints(app.BeatIndex);
            app.ShiftedCheckBox.Value = app.ShiftedBeats(app.BeatIndex);
            %app.Slider.Value = app.scgScores(app.BeatIndex);
            if (app.scgRatings(app.BeatIndex) == -1)
                app.NoRatingButton_2.Value = 1; 
            elseif (app.scgRatings(app.BeatIndex) == 0)
                app.BadButton.Value = 1;
            elseif (app.scgRatings(app.BeatIndex) == 1)
                app.AverageButton.Value = 1;
            elseif (app.scgRatings(app.BeatIndex) == 2)
                app.GoodButton.Value = 1;
            end
        end


        function [x_val, y_val] = maxsearch(app, x_plot, y_plot, x_guess)
            %% FUNCTION
            TF = islocalmax(y_plot); %Identfies all local maxima
            x_locs = x_plot(TF);  %array of all local max x values
            y_locs = y_plot(TF); %array of all local max y values
            locs_max = x_locs-x_guess; %finds how far each maxima is from the click
            locs_max = abs(locs_max); %some local max x values may be less than click/guess
            [~,max_loc] = min(locs_max); %identify the smallest distance (find closest maxima)
            x_val = x_locs(max_loc);
            y_val = y_locs(max_loc);
        end
        function [x_val, y_val] = minsearch(app, x_plot, y_plot, x_guess)
            %% FUNCTION
            TF = islocalmin(y_plot); %Identfies all local minima
            x_locs = x_plot(TF);  %array of all local min x values
            y_locs = y_plot(TF); %array of all local min y values
            locs_min = x_locs-x_guess; %finds how far each mimima is from the click
            locs_min = abs(locs_min); %some local min x values may be less than click/guess
            [~,min_loc] = min(locs_min); %identify the smallest distance (find closest minima)
            x_val = x_locs(min_loc);
            y_val = y_locs(min_loc);
        end
        function [x_val, y_val] = directSelect(app, x_plot, y_plot, x_click)
            [~, idx] = min(abs(x_plot - x_click));  % Find closest x in data
            x_val = x_plot(idx);
            y_val = y_plot(idx);
        end

        function displayEntireHeatMap(app)
            % Initially display all beats
            selectedBeats = app.scgBeats;
            
            % Create x-axis values similar to the plot command
            xValues = linspace(0, app.lengthOfXAxis, size(selectedBeats, 2));
            
            % Use imagesc to display the image in the UIAxes
            hImage = imagesc(app.HeatMap, xValues, 1:size(selectedBeats, 1), selectedBeats);
            
            % Set 'HitTest' property to 'off' for the image to avoid blocking clicks
            hImage.HitTest = 'off';
            
            % Set 'PickableParts' property of the UIAxes to 'all' to ensure the entire axes is clickable
            app.HeatMap.PickableParts = 'all';
            
            % Apply the colormap to the UIAxes
            map = contrast(app.scgBeats);
            colormap(app.HeatMap, map);
            
            % Label the axes
            xlabel(app.HeatMap, 'Time (ms)');
            ylabel(app.HeatMap, 'Beat Index');
            
            % Add a colorbar to the UIAxes
            %colorbar(app.HeatMap);
            
            % Set the x-axis limits (domain)
            xlim(app.HeatMap, [xValues(1) xValues(end)]); % Adjust x-axis limits based on selected data
            
            % Set the y-axis limits (range)
            ylim(app.HeatMap, [1 size(selectedBeats, 1)]); % Set y-axis to show the number of beats
            
            % Set y-axis labels to reflect the actual beat indices
            yticks(app.HeatMap, 1:100:size(selectedBeats, 1));
            yticklabels(app.HeatMap, 1:100:size(selectedBeats, 1));
            
            % Close the blank figure that pops up from imagesc
            delete(findall(groot, 'Type', 'figure', 'Number', 1));
        end

        function displayScopedHeatMap(app)
            % Determine the range of beats to display
            currentIdx = app.BeatIndex;
            app.numRows = size(app.scgBeats, 1); % Total number of beats
            numBeatsToShow = 100; % Number of beats to show
        
            % Calculate start and end indices
            startIndex = max(1, currentIdx - floor(numBeatsToShow / 2));
            endIndex = min(app.numRows, startIndex + numBeatsToShow - 1);
        
            % Adjust startIndex if we are at the end of the data
            if endIndex - startIndex + 1 < numBeatsToShow
                startIndex = max(1, endIndex - numBeatsToShow + 1);
            end
        
            % Extract the range of beats to display
            selectedBeats = app.scgBeats(startIndex:endIndex, :);
        
            % Create x-axis values similar to the plot command
            xValues = linspace(0, app.lengthOfXAxis, size(selectedBeats, 2));
        
            % Use imagesc to display the image in the UIAxes
            temp = imagesc(app.HeatMap, xValues, 1:size(selectedBeats, 1), selectedBeats);

            % Set 'HitTest' property to 'off' for the image to avoid blocking clicks
            temp.HitTest = 'off';
        
            % Apply the colormap to the UIAxes
            map = contrast(app.scgBeats);
            colormap(app.HeatMap, map);
        
            % Label the axes
            xlabel(app.HeatMap, ['Time (ms)']);
            ylabel(app.HeatMap, 'Beat Index');
        
            % Add a colorbar to the UIAxes
            %colorbar(app.HeatMap);
        
            % Set the x-axis limits (domain)
            xlim(app.HeatMap, [xValues(1) xValues(end)]); % Adjust x-axis limits based on selected data
        
            % Set the y-axis limits (range)
            ylim(app.HeatMap, [1 numBeatsToShow]); % Set y-axis to show the number of beats
        
            % Set y-axis labels to reflect the actual beat indices
            yticks(app.HeatMap, 1:10:numBeatsToShow);
            yticklabels(app.HeatMap, startIndex:10:endIndex);
        
            % Highlight the current beat being examined
            hold(app.HeatMap, 'on');
            currentBeatIndex = currentIdx - startIndex + 1; % Position of the current beat in the displayed range
            plot(app.HeatMap, [xValues(1), xValues(end)], [currentBeatIndex, currentBeatIndex], 'g', 'LineWidth', 2);
            hold(app.HeatMap, 'off');

        
            % Close the blank figure that pops up from imagesc
            delete(findall(groot, 'Type', 'figure', 'Number', 1));

            vertline1 = xline(app.HeatMap, str2double(app.AOLB.Text), 'g', 'LineWidth', 2, 'Tag', 'HeatMap', 'HitTest', 'off');
            vertline2 = xline(app.HeatMap, str2double(app.AOUB.Text), 'g', 'LineWidth', 2, 'Tag', 'HeatMap', 'HitTest', 'off');
            vertline3 = xline(app.HeatMap, str2double(app.ACLB.Text), 'g', 'LineWidth', 2, 'Tag', 'HeatMap', 'HitTest', 'off');
            vertline4 = xline(app.HeatMap, str2double(app.ACUB.Text), 'g', 'LineWidth', 2, 'Tag', 'HeatMap', 'HitTest', 'off');
            drawnow;
        end
        function lastkbeats(app)
        
            % Calculate the start and end indices for the previous beats
            startIdx = max(1, app.BeatIndex - app.lastKBeatsValue);  % Ensure startIdx is at least 1
            endIdx = max(1, app.BeatIndex - 1);  % The last beat to be shown is the one right before the currentBeat
        
            % Clear the previous plot
            cla(app.LastXBeats);
        
            % Initialize an array to store the beats being plotted
            beatsToPlot = [];
        
            % Plot the previous beats in light gray color
            hold(app.LastXBeats, 'on');
            for i = startIdx:endIdx
                plot(app.LastXBeats, linspace(0, app.lengthOfXAxis, app.numColumns), ...
                     app.scgBeats(i, :), 'Color', [0.8, 0.8, 0.8], 'Tag', 't', 'HitTest', 'off');
                beatsToPlot = [beatsToPlot; app.scgBeats(i, :)];  % Add the current beat to the array
            end
        
            % Calculate the mean and standard deviation of the plotted beats
            if ~isempty(beatsToPlot)
                avgBeat = mean(beatsToPlot, 1);  % Calculate the average across the beats
                stdevBeat = std(beatsToPlot, 0, 1);  % Calculate the standard deviation across the beats
        
                % Plot the average of the plotted beats as a bright red line
                plot(app.LastXBeats, linspace(0, app.lengthOfXAxis, app.numColumns), ...
                     avgBeat, 'Color', [1, 0, 0], 'LineWidth', 2, 'Tag', 'avg', 'HitTest', 'off');
        
                % Plot Mean + 1*STDEV in purple
                plot(app.LastXBeats, linspace(0, app.lengthOfXAxis, app.numColumns), ...
                     avgBeat + stdevBeat, 'Color', [0.5, 0, 0.5], 'LineWidth', 1.5, 'Tag', 'mean_plus_stdev', 'HitTest', 'off');
        
                % Plot Mean - 1*STDEV in purple
                plot(app.LastXBeats, linspace(0, app.lengthOfXAxis, app.numColumns), ...
                     avgBeat - stdevBeat, 'Color', [0.5, 0, 0.5], 'LineWidth', 1.5, 'Tag', 'mean_minus_stdev', 'HitTest', 'off');
            end
        
            hold(app.LastXBeats, 'off');
        end


        function drawHeatVert(app, lineHandle)
                vertline = xline(app.SCG, lineHandle.Value, 'g', 'LineWidth', 2, 'Tag', 'HeatMap', 'HitTest', 'off');
                drawnow;
                app.SCGVertHeatMapLines = [app.SCGVertHeatMapLines; vertline];
        end

        function drawAllHeatVert(app)
            vertline1 = xline(app.SCG, str2double(app.AOLB.Text), 'g', 'Tag', 'HeatMap', 'HitTest', 'off');
            vertline2 = xline(app.SCG, str2double(app.AOUB.Text), 'g', 'Tag', 'HeatMap', 'HitTest', 'off');
            vertline3 = xline(app.SCG, str2double(app.ACLB.Text), 'g', 'Tag', 'HeatMap', 'HitTest', 'off');
            vertline4 = xline(app.SCG, str2double(app.ACUB.Text), 'g', 'Tag', 'HeatMap', 'HitTest', 'off');
            vertline5 = xline(app.LastXBeats, str2double(app.AOLB.Text), 'g', 'Tag', 'HeatMap', 'HitTest', 'off');
            vertline6 = xline(app.LastXBeats, str2double(app.AOUB.Text), 'g', 'Tag', 'HeatMap', 'HitTest', 'off');
            vertline7 = xline(app.LastXBeats, str2double(app.ACLB.Text), 'g', 'Tag', 'HeatMap', 'HitTest', 'off');
            vertline8 = xline(app.LastXBeats, str2double(app.ACUB.Text), 'g', 'Tag', 'HeatMap', 'HitTest', 'off');
            vertline9 = xline(app.ECG, str2double(app.AOLB.Text), 'g', 'Tag', 'HeatMap', 'HitTest', 'off');
            vertline10 = xline(app.ECG, str2double(app.AOUB.Text), 'g', 'Tag', 'HeatMap', 'HitTest', 'off');
            vertline11 = xline(app.ECG, str2double(app.ACLB.Text), 'g', 'Tag', 'HeatMap', 'HitTest', 'off');
            vertline12 = xline(app.ECG, str2double(app.ACUB.Text), 'g', 'Tag', 'HeatMap', 'HitTest', 'off');
            drawnow;
        end

        function removeHeatVert(app)
            delete(app.SCGVertHeatMapLines);
            app.SCGVertHeatMapLines = [];
        end

        function updateLowestUnannotatedBeat(app)
            temp = 1
            while (app.scgRatings(temp) ~= -1 & temp < app.numRows)
                temp = temp + 1;
            end
            app.lowestUnannotatedBeatIndex = temp;
            app.LowestUnannotatedBeatLabel.Text = "Lowest Unannotated Beat: " + num2str(app.lowestUnannotatedBeatIndex);
        end
        function shiftedPressed(app)
            app.ShiftedCheckBox.Value = ~app.ShiftedCheckBox.Value;
        end
        function zPressed(app)
            app.AOButton.Value = 1;
        end
        function xPressed(app)
            app.ACButton.Value = 1;
        end
        function cPressed(app)
            app.ACvButton.Value = 1;
        end
        function vPressed(app)
            app.MOButton.Value = 1;
        end
        function checkForInprogress(app)
            subjectID = num2str(app.currentSubjectID);
            fprintf(num2str(isfile(['INPROGRESS_S' subjectID '_scg_' app.annotator '.mat'])));
            disp(app.TypeoffiletoaccessButtonGroup.SelectedObject);
            if (app.StartingNewButton.Value)
                if (isfile(['INPROGRESS_S' subjectID '_scg_' app.annotator '.mat']))
                    app.FileAlreadyInProgressWarning.Text = "WARNING: A file with this subject and annotator is already created (INPROGRESS). If you proceed, the old INPROGRESS file will be lost.";
                elseif (isfile(['FINISHED_S' subjectID '_scg_' app.annotator '.mat']))
                    app.FileAlreadyInProgressWarning.Text = "WARNING: A file with this subject and annotator is already created (FINISHED). If you proceed, the old FINISHED file will be lost.";
                else
                    app.FileAlreadyInProgressWarning.Text = "";
    
                end
            else
                app.FileAlreadyInProgressWarning.Text = "";
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: StartAnnotatingButton
        function StartAnnotatingButtonPushed(app, event)
            

            %initialize annotation arrays
            loadData(app);
            
            if (~app.pickingUp && ~app.revisitingFinished)
                app.numColumns = size(app.scgBeats, 2);
                app.numRows = size(app.scgBeats, 1);
                app.scgScores = zeros(1, app.numRows);
                app.AOLowerBounds = zeros(1, app.numRows);
                app.AOUpperBounds = zeros(1, app.numRows);
                app.AOMaxPoints = zeros(1, app.numRows);
                app.ACLowerBounds = zeros(1, app.numRows);
                app.ACUpperBounds = zeros(1, app.numRows);
                app.ACMaxPoints = zeros(1, app.numRows);
                app.ACvPoints = zeros(1, app.numRows);
                app.MOPoints = zeros(1, app.numRows);
                app.ShiftedBeats = zeros(1, app.numRows);
                app.scgRatings = -1 * ones(1, app.numRows);
            else
                app.numColumns = size(app.scgBeats, 2);
                app.numRows = size(app.scgBeats, 1);
                app.AOLB.Text = num2str(app.HeatmapAOLowerBound);
                app.AOUB.Text = num2str(app.HeatmapAOUpperBound);
                app.ACLB.Text = num2str(app.HeatmapACLowerBound);
                app.ACUB.Text = num2str(app.HeatmapACUpperBound);
            end
            app.MainMenuPanel.Visible = 'off';
            app.AnnotatingPanel.Visible = 'on';
            app.SubjectLabel.Text = ['SubjectID: ' num2str(app.currentSubjectID)];
            app.BeatIndex = 1;
            app.lengthOfXAxis = app.numColumns / ((app.samplingRateValueHz) / 1000); % divided by 1000 to account for the Hz change (instead of KHz)
            plot(app.SCG, linspace(0, app.lengthOfXAxis, app.numColumns), app.scgBeats(app.BeatIndex, :), 'Tag', 'currentBeat', 'HitTest', 'off');
            app.BeatLabel.Text = ['Beat #: ' num2str(app.BeatIndex)];
            app.AnnotatorLabel.Text = ['Annotator: ' app.annotator];
            app.AO_extrema.Value = 'Max';
            app.AC_extrema.Value = 'Max';
            app.ACv_extrema.Value = 'Min';
            app.MO_extrema.Value = 'Min';
            displayEntireHeatMap(app);
            lastkbeats(app);
            refillFields(app);
            plottedLine = plot(app.SCG, linspace(0, app.lengthOfXAxis, app.numColumns), app.scgBeats(app.BeatIndex, :), 'Tag', 'currentBeat', 'HitTest', 'off');
            drawExistingAnnotations(app, plottedLine);
            drawAllHeatVert(app);
            updateLowestUnannotatedBeat(app)
            if app.pickingUp || app.revisitingFinished
                app.JumpTo.Value = app.lowestUnannotatedBeatIndex;
                disp("test");
                JumptoButtonPushed(app, event);
            end
            if (app.ecgBeatsExist && size(app.ecgBeats, 1) == app.numRows)
                plot(app.ECG, linspace(0, app.lengthOfXAxis, app.numColumns), app.ecgBeats(app.BeatIndex, :), 'Tag', 'currentBeat', 'HitTest', 'off');
            end
            app.mainMenuShowing = 0;
            focus(app.UIFigure)
        end

        % Button pushed function: NextSaveButton
        function NextSaveButtonPushed(app, event)
            %prevent button spam
            if app.AntiSpam
                return;
            end
            app.AntiSpam = true;
            app.AOButton.Value = true; %*
            %save annotations on current beat:
            saveInstance(app);
            %change beat to next
            app.BeatIndex = app.BeatIndex + 1;
            if app.BeatIndex > size(app.scgBeats, 1)
                app.BeatIndex = 1;
            end
            plottedLine = plot(app.SCG, linspace(0, app.lengthOfXAxis, app.numColumns), app.scgBeats(app.BeatIndex, :), 'Tag', 'currentBeat', 'HitTest', 'off');
            displayScopedHeatMap(app);
            app.BeatLabel.Text = ['Beat #: ' num2str(app.BeatIndex)];
            lastkbeats(app);
            refillFields(app);
            drawExistingAnnotations(app, plottedLine);
            drawAllHeatVert(app);
            updateLowestUnannotatedBeat(app);
            if (app.ecgBeatsExist && size(app.ecgBeats, 1) == app.numRows)
                plot(app.ECG, linspace(0, app.lengthOfXAxis, app.numColumns), app.ecgBeats(app.BeatIndex, :), 'Tag', 'currentBeat', 'HitTest', 'off');
            end
            app.AntiSpam = false;
            app.notFinished = 1;
            focus(app.UIFigure)
            saveData(app);
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            %prevent button spam
            if app.AntiSpam
                return;
            end
            app.AntiSpam = true;
            app.AOButton.Value = true;
            %save annotations on current beat:
            saveInstance(app);
            app.BeatIndex = app.BeatIndex - 1;
            if app.BeatIndex < 1
                app.BeatIndex = size(app.scgBeats, 1);
            end
            plottedLine = plot(app.SCG, linspace(0, app.lengthOfXAxis, app.numColumns), app.scgBeats(app.BeatIndex, :), 'Tag', 'currentBeat', 'HitTest', 'off');
            displayScopedHeatMap(app);
            app.BeatLabel.Text = ['Beat #: ' num2str(app.BeatIndex)];
            lastkbeats(app);
            refillFields(app);
            drawExistingAnnotations(app, plottedLine);
            drawAllHeatVert(app);
            updateLowestUnannotatedBeat(app);
            if (app.ecgBeatsExist && size(app.ecgBeats, 1) == app.numRows)
                plot(app.ECG, linspace(0, app.lengthOfXAxis, app.numColumns), app.ecgBeats(app.BeatIndex, :), 'Tag', 'currentBeat', 'HitTest', 'off');
            end
            focus(app.UIFigure)
            app.AntiSpam = false;

        end

        % Value changed function: SubjectEditField
        function SubjectEditFieldValueChanged(app, event)
            value = app.SubjectEditField.Value;
            app.currentSubjectID = value;
            checkAndEnableStartButton(app);
            checkForInprogress(app);
            
        end

        % Value changed function: AnnotatorFirstNameEditField
        function AnnotatorFirstNameEditFieldValueChanged(app, event)
            value = app.AnnotatorFirstNameEditField.Value;
            app.annotator = value;
            checkAndEnableStartButton(app);
            checkForInprogress(app);
        end

        % Button pushed function: Next20Button
        function Next20ButtonPushed(app, event)
            %prevent button spam
            if app.AntiSpam
                return;
            end
            app.AntiSpam = true;
            app.AOButton.Value = true;
            %save annotations on current beat:
            saveInstance(app);
            app.BeatIndex = app.BeatIndex + 20;
            if app.BeatIndex > size(app.scgBeats, 1)
                app.BeatIndex = mod(app.BeatIndex, size(app.scgBeats, 1) + 1) ;
            end
            plottedLine = plot(app.SCG, linspace(0, app.lengthOfXAxis, app.numColumns), app.scgBeats(app.BeatIndex, :), 'Tag', 'currentBeat', 'HitTest', 'off');
            displayScopedHeatMap(app);
            app.BeatLabel.Text = ['Beat #: ' num2str(app.BeatIndex)];
            lastkbeats(app);
            refillFields(app);
            drawExistingAnnotations(app, plottedLine);
            drawAllHeatVert(app);
            updateLowestUnannotatedBeat(app);
            if (app.ecgBeatsExist && size(app.ecgBeats, 1) == app.numRows)
                plot(app.ECG, linspace(0, app.lengthOfXAxis, app.numColumns), app.ecgBeats(app.BeatIndex, :), 'Tag', 'currentBeat', 'HitTest', 'off');
            end
            focus(app.UIFigure)
            app.AntiSpam = false;
        end

        % Button pushed function: Previous20Button
        function Previous20ButtonPushed(app, event)
            %prevent button spam
            if app.AntiSpam
                return;
            end
            app.AntiSpam = true;
            app.AOButton.Value = true;
            %save annotations on current beat:
            saveInstance(app);
            app.BeatIndex = app.BeatIndex - 20;
            if app.BeatIndex < 1
                app.BeatIndex = mod(app.BeatIndex, size(app.scgBeats, 1) + 1) ;
            end
            plottedLine = plot(app.SCG, linspace(0, app.lengthOfXAxis, app.numColumns), app.scgBeats(app.BeatIndex, :), 'Tag', 'currentBeat', 'HitTest', 'off');
            displayScopedHeatMap(app);
            app.BeatLabel.Text = ['Beat #: ' num2str(app.BeatIndex)];
            lastkbeats(app);
            refillFields(app);
            drawExistingAnnotations(app, plottedLine);
            drawAllHeatVert(app);
            updateLowestUnannotatedBeat(app);
            if (app.ecgBeatsExist && size(app.ecgBeats, 1) == app.numRows)
                plot(app.ECG, linspace(0, app.lengthOfXAxis, app.numColumns), app.ecgBeats(app.BeatIndex, :), 'Tag', 'currentBeat', 'HitTest', 'off');
            end
            focus(app.UIFigure)
            app.AntiSpam = false;
        end

        % Button down function: SCG
        function SCGButtonDown(app, event)
            if app.AOButton.Value
                lineTag = 'AO';
                lineColor = 'r';
            elseif app.ACButton.Value
                lineTag = 'AC';
                lineColor = 'b';
            elseif app.ACvButton.Value
                lineTag = 'ACv';
                lineColor = 'c'
            else
                lineTag = 'MO';
                lineColor = 'g'
            end
        
            % Delete existing vertical lines with the specified tag
            % If there are 3 lines already, will delete both and start over
            AOConstantLines = findobj(event.Source, 'Type', 'ConstantLine', 'Tag', 'AO');
            ACConstantLines = findobj(event.Source, 'Type', 'ConstantLine', 'Tag', 'AC');
            ACvConstantLines = findobj(event.Source, 'Type', 'ConstantLine', 'Tag', 'ACv');
            MOConstantLines = findobj(event.Source, 'Type', 'ConstantLine', 'Tag', 'MO');
        
            if (length(AOConstantLines) >= 1) && strcmp(lineTag, 'AO')
                delete(AOConstantLines);
                delete(app.AOMaxHandle);
                app.AO1.Value = 0;
                app.AO2.Value = 0;
                app.AOPoint.Value = 0;
            end
           
            if (length(ACConstantLines) >= 1) && strcmp(lineTag, 'AC')
                delete(ACConstantLines);
                delete(app.ACMaxHandle);
                app.AC1.Value = 0;
                app.AC2.Value = 0;
                app.ACPoint.Value = 0;
            end

            if (length(ACvConstantLines) >= 1) && strcmp(lineTag, 'ACv')
                delete(ACvConstantLines);
                delete(app.ACvMaxHandle);
                app.ACvPoint.Value = 0;
            end
           
            if (length(MOConstantLines) >= 1) && strcmp(lineTag, 'MO')
                delete(MOConstantLines);
                delete(app.MOMaxHandle);
                app.MOPoint.Value = 0;
            end
        
            % Save x-Position of mouse click
            xPosClick = event.Source.CurrentPoint(1, 1);
            fprintf('Mouse click at x = %.2f\n', xPosClick); % Debugging: log the click position
            
            % Find the Line object
            lineHandle = findobj(event.Source, 'Type', 'Line', 'Tag', 'currentBeat');
            
            % Prevent creating vertical lines left and right of the "data"
            if xPosClick >= lineHandle.XData(1) && xPosClick <= lineHandle.XData(end) * 1.01
                % Draw vertical line into the uiaxes with the specified tag
                vertline = xline(app.SCG, xPosClick, 'Color', lineColor, 'Tag', lineTag, 'HitTest', 'off');
                drawnow;
                fprintf('Vertical line drawn at x = %.2f\n', xPosClick); % Debugging: log vertical line drawing
                
                % Set data in edit fields based on the specified tag
                allConstantLines = findobj(event.Source, 'Type', 'ConstantLine', 'Tag', lineTag);
                if strcmp(lineTag, 'AC')
                    if isscalar(allConstantLines)
                        x0 = xPosClick;
                        vertline.Visible = 'off';
                        if app.CtrlPressed
                            [localExtremaX,localExtremaY] = directSelect(app, lineHandle.XData, lineHandle.YData, x0);
                        else
                            if app.AC_extrema.Value == "Max"
                                [localExtremaX,localExtremaY] = maxsearch(app, lineHandle.XData, lineHandle.YData, x0);
                            else
                                [localExtremaX,localExtremaY] = minsearch(app, lineHandle.XData, lineHandle.YData, x0);
                            end
                        end
                        app.ACPoint.Value = localExtremaX;
                        hold(app.SCG, 'on');
                        app.ACMaxHandle = plot(app.SCG, localExtremaX, localExtremaY, 'bo', 'MarkerSize', 10, 'LineWidth', 2, 'HitTest', 'off');
                        hold(app.SCG, 'off');
                        app.ACvButton.Value = true;
                    end
                elseif strcmp(lineTag, 'AO')
                    if isscalar(allConstantLines)
                        x0 = xPosClick;
                        vertline.Visible = 'off';
                        if app.CtrlPressed
                            [localExtremaX,localExtremaY] = directSelect(app, lineHandle.XData, lineHandle.YData, x0);
                        else
                            if app.AO_extrema.Value == "Max"
                                [localExtremaX,localExtremaY] = maxsearch(app, lineHandle.XData, lineHandle.YData, x0);
                            else
                                [localExtremaX,localExtremaY] = minsearch(app, lineHandle.XData, lineHandle.YData, x0);
                            end
                        end
                        app.AOPoint.Value = localExtremaX;
                        hold(app.SCG, 'on');
                        app.AOMaxHandle = plot(app.SCG, localExtremaX, localExtremaY, 'ro', 'MarkerSize', 10, 'LineWidth', 2, 'HitTest', 'off');
                        hold(app.SCG, 'off');
                        app.ACButton.Value = true;
                    end
                elseif strcmp(lineTag, 'ACv')
                    if isscalar(allConstantLines)
                        x0 = xPosClick;
                        vertline.Visible = 'off';
                        if app.CtrlPressed
                            [localExtremaX,localExtremaY] = directSelect(app, lineHandle.XData, lineHandle.YData, x0);
                        else
                            if app.ACv_extrema.Value == "Max"
                                [localExtremaX,localExtremaY] = maxsearch(app, lineHandle.XData, lineHandle.YData, x0);
                            else
                                [localExtremaX,localExtremaY] = minsearch(app, lineHandle.XData, lineHandle.YData, x0);
                            end
                        end
                        app.ACvPoint.Value = localExtremaX;
                        hold(app.SCG, 'on');
                        app.ACvMaxHandle = plot(app.SCG, localExtremaX, localExtremaY, 'co', 'MarkerSize', 10, 'LineWidth', 2, 'HitTest', 'off');
                        hold(app.SCG, 'off');
                        app.MOButton.Value = true;
                    end
                elseif strcmp(lineTag, 'MO')
                    if isscalar(allConstantLines)
                        x0 = xPosClick;
                        vertline.Visible = 'off';
                        if app.CtrlPressed
                            [localExtremaX,localExtremaY] = directSelect(app, lineHandle.XData, lineHandle.YData, x0);
                        else
                            if app.MO_extrema.Value == "Max"
                                [localExtremaX,localExtremaY] = maxsearch(app, lineHandle.XData, lineHandle.YData, x0);
                            else
                                [localExtremaX,localExtremaY] = minsearch(app, lineHandle.XData, lineHandle.YData, x0);
                            end
                        end
                        app.MOPoint.Value = localExtremaX;
                        hold(app.SCG, 'on');
                        app.MOMaxHandle = plot(app.SCG, localExtremaX, localExtremaY, 'go', 'MarkerSize', 10, 'LineWidth', 2, 'HitTest', 'off');
                        hold(app.SCG, 'off');
                        app.AOButton.Value = true;
                    end
                end
            else
                fprintf('Click position out of valid range\n'); % Debugging: log invalid click range
            end
        
            pause(0.01);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            % Rounding the value allows the slider to snap to a number
            value = round(value);
            app.Slider.Value = value;
            focus(app.UIFigure)

        end

        % Value changed function: SamplingRate
        function SamplingRateValueChanged(app, event)
            value = app.SamplingRate.Value;
            app.samplingRateValueHz = value;
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if (app.mainMenuShowing)
                delete(app);
            end
            % Create a confirmation dialog with additional buttons
            choice = uiconfirm(app.UIFigure, 'Make sure you have saved!', 'Confirmation', ...
                               'Options', {'INPROGRESS Save and Close', 'Close without Saving', 'FINISHED Save and Close', 'Cancel'}, ...
                               'DefaultOption', 1, ...
                               'CancelOption', 4);
            
            % Handle the user choice
            switch choice
                case 'INPROGRESS Save and Close'
                    disp('User selected Save and Close');
                    app.notFinished = 1;
                    saveData(app);
                    delete(app);
                case 'Close without Saving'
                    disp('User selected Close without Saving');
                    delete(app);
                case 'FINISHED Save and Close'
                    disp('User selected FINISHED Save and Close');
                    app.notFinished = 0;
                    saveData(app);
                    delete(app);
                case 'Cancel'
                    disp('User selected Cancel');
            end
        end

        % Button down function: HeatMap
        function HeatMapButtonDown(app, event)
            % Find all existing constant lines with the tag 'HeatMap'
            allConstantLines = findobj(app.HeatMap, 'Type', 'ConstantLine', 'Tag', 'HeatMap');
        
            % Check the number of constant lines found
            fprintf('Number of constant lines found: %d\n', length(allConstantLines));
            
            if length(allConstantLines) >= 4
                % Delete all vertical lines from the plot and clear the handles array
                delete(allConstantLines);
                app.HeatmapLineHandles = [];
                removeHeatVert(app);
            end
            
            % Get the x-coordinate of the mouse click
            xPosClick = event.Source.CurrentPoint(1, 1);
            fprintf('Mouse click at x = %.2f\n', xPosClick); % Debugging: log the click position
            
            % Draw a vertical line at the clicked position
            vertline = xline(app.HeatMap, xPosClick, 'r', 'Tag', 'HeatMap', 'HitTest', 'off');
            drawnow;
            fprintf('Vertical line drawn at x = %.2f\n', xPosClick); % Debugging: log vertical line drawing
            
            % Add the new line handle to the array
            app.HeatmapLineHandles = [app.HeatmapLineHandles; vertline];

            for index = 1:1:length(app.HeatmapLineHandles)
                if (index == 1)
                    app.AOLB.Text = num2str(app.HeatmapLineHandles(1).Value);
                elseif (index == 2)
                    app.AOUB.Text = num2str(app.HeatmapLineHandles(2).Value);
                elseif (index == 3)
                    app.ACLB.Text = num2str(app.HeatmapLineHandles(3).Value);
                else
                    app.ACUB.Text = num2str(app.HeatmapLineHandles(4).Value);
                end
            end
            drawHeatVert(app, app.HeatmapLineHandles(length(app.HeatmapLineHandles)));

        end

        % Button pushed function: ShowentireHeatMapButton
        function ShowentireHeatMapButtonPushed(app, event)
            displayEntireHeatMap(app);
            vertline1 = xline(app.HeatMap, str2double(app.AOLB.Text), 'g', 'Tag', 'HeatMap', 'HitTest', 'off');
            vertline2 = xline(app.HeatMap, str2double(app.AOUB.Text), 'g', 'Tag', 'HeatMap', 'HitTest', 'off');
            vertline3 = xline(app.HeatMap, str2double(app.ACLB.Text), 'g', 'Tag', 'HeatMap', 'HitTest', 'off');
            vertline4 = xline(app.HeatMap, str2double(app.ACUB.Text), 'g', 'Tag', 'HeatMap', 'HitTest', 'off');
            drawnow;
            focus(app.UIFigure)
        end

        % Button pushed function: JumptoButton
        function JumptoButtonPushed(app, event)
            %anti spam
            if app.AntiSpam
                return;
            end
            app.AntiSpam = true;
            app.AOButton.Value = true;
            saveInstance(app);
            %change beat to next
            app.BeatIndex = app.JumpTo.Value;
            if app.BeatIndex > size(app.scgBeats, 1)
                app.BeatIndex = 1;
            end
            if app.BeatIndex <= 0
                app.BeatIndex = 1;
            end
            plottedLine = plot(app.SCG, linspace(0, app.lengthOfXAxis, app.numColumns), app.scgBeats(app.BeatIndex, :), 'Tag', 'currentBeat', 'HitTest', 'off');
            displayScopedHeatMap(app);
            app.BeatLabel.Text = ['Beat #: ' num2str(app.BeatIndex)];
            lastkbeats(app);
            refillFields(app);
            drawExistingAnnotations(app, plottedLine);
            drawAllHeatVert(app);
            updateLowestUnannotatedBeat(app);
            if (app.ecgBeatsExist && size(app.ecgBeats, 1) == app.numRows)
                plot(app.ECG, linspace(0, app.lengthOfXAxis, app.numColumns), app.ecgBeats(app.BeatIndex, :), 'Tag', 'currentBeat', 'HitTest', 'off');
            end
            focus(app.UIFigure)
            app.AntiSpam = false;
        end

        % Selection changed function: ButtonGroup_3
        function ButtonGroup_3SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_3.SelectedObject;
            NextSaveButtonPushed(app, event);
            focus(app.UIFigure)
        end

        % Selection changed function: TypeoffiletoaccessButtonGroup
        function TypeoffiletoaccessButtonGroupSelectionChanged(app, event)
            selectedButton = app.TypeoffiletoaccessButtonGroup.SelectedObject;
            if (selectedButton == app.InProgressButton)
                app.pickingUp = 1;
                app.revisitingFinished = 0;
            elseif (selectedButton == app.FinishedButton)
                app.revisitingFinished = 1;
                app.pickingUp = 0;
            else
                app.pickingUp = 0;
                app.revisitingFinished = 0;
            end
            checkForInprogress(app);
            checkAndEnableStartButton(app);
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            value = app.XEditField.Value;
            app.lastKBeatsValue = value;
            lastkbeats(app);
            focus(app.UIFigure);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'control'
                    app.CtrlPressed = true;
            end
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            key = event.Key;
            switch key
                case 'control'
                    app.CtrlPressed = false;
                case 's'
                    fprintf("test s click")
                    shiftedPressed(app)
                case 'z'
                    fprintf("test z click")
                    zPressed(app)
                case 'x'
                    fprintf("test x click")
                    xPressed(app)
                case 'c'
                    fprintf("test c click")
                    cPressed(app)
                case 'v'
                    fprintf("test v click")
                    vPressed(app)
            end
        end

        % Button pushed function: Clear_Annotations
        function Clear_AnnotationsButtonPushed(app, event)
            app.NoRatingButton_2.Value = true;
            app.AOButton.Value = true;
            app.AOPoint.Value = 0;
            app.ACPoint.Value = 0;
            app.ACvPoint.Value = 0;
            app.MOPoint.Value = 0;
            saveInstance(app);
            plottedLine = plot(app.SCG, linspace(0, app.lengthOfXAxis, app.numColumns), app.scgBeats(app.BeatIndex, :), 'Tag', 'currentBeat', 'HitTest', 'off');
            drawExistingAnnotations(app, plottedLine);
            drawAllHeatVert(app);
            updateLowestUnannotatedBeat(app);
            focus(app.UIFigure)
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_2.SelectedObject;
            focus(app.UIFigure)
        end

        % Button pushed function: BacktomenuButton
        function BacktomenuButtonPushed(app, event)
            choice = uiconfirm(app.UIFigure, 'Make sure you have saved!', 'Confirmation', ...
                               'Options', {'INPROGRESS Save and Close', 'Close without Saving', 'FINISHED Save and Close', 'Cancel'}, ...
                               'DefaultOption', 1, ...
                               'CancelOption', 4);
            
            % Handle the user choice
            switch choice
                case 'INPROGRESS Save and Close'
                    disp('User selected Save and Close');
                    app.notFinished = 1;
                    saveData(app);
                    
                case 'Close without Saving'
                    disp('User selected Close without Saving');
                    
                case 'FINISHED Save and Close'
                    disp('User selected FINISHED Save and Close');
                    app.notFinished = 0;
                    saveData(app);
                    
                case 'Cancel'
                    disp('User selected Cancel');
                    return
            end
                delete(app.UIFigure);
                % Open 2nd instance of app
                AnnotationGui_v11_1();  % <--------------The name of your app
                % Delete old instance
                
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.AnnotatingPanel = uipanel(app.UIFigure);
            app.AnnotatingPanel.Title = 'Annotating';
            app.SCG = uiaxes(app.AnnotatingPanel);
            title(app.SCG, 'SCG')
            xlabel(app.SCG, 'Time (ms)')
            app.SCG.ButtonDownFcn = createCallbackFcn(app, @SCGButtonDown, true);
            app.ECG = uiaxes(app.AnnotatingPanel);
            title(app.ECG, 'ECG')
            xlabel(app.ECG, 'Time(ms)')
            app.LastXBeats = uiaxes(app.AnnotatingPanel);
            title(app.LastXBeats, 'Last X Beats')
            xlabel(app.LastXBeats, 'Time (ms)')
            zlabel(app.LastXBeats, 'Z')
            app.HeatMap = uiaxes(app.AnnotatingPanel);
            title(app.HeatMap, 'HeatMap')
            xlabel(app.HeatMap, 'X')
            ylabel(app.HeatMap, 'Y')
            zlabel(app.HeatMap, 'Z')
            app.HeatMap.ButtonDownFcn = createCallbackFcn(app, @HeatMapButtonDown, true);
            app.ButtonGroup_2 = uibuttongroup(app.AnnotatingPanel);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.AOButton = uiradiobutton(app.ButtonGroup_2);
            app.AOButton.Text = 'AO:';
            app.AOButton.Value = true;
            app.ACButton = uiradiobutton(app.ButtonGroup_2);
            app.ACButton.Text = 'AC:';
            app.AOPoint = uieditfield(app.ButtonGroup_2, 'numeric');
            app.ACPoint = uieditfield(app.ButtonGroup_2, 'numeric');
            app.AC_extrema = uiswitch(app.ButtonGroup_2, 'slider');
            app.AC_extrema.Items = {'Min', 'Max'};
            app.AC_extrema.Value = 'Min';
            app.AO_extrema = uiswitch(app.ButtonGroup_2, 'slider');
            app.AO_extrema.Items = {'Min', 'Max'};
            app.AO_extrema.Value = 'Min';
            app.ACvButton = uiradiobutton(app.ButtonGroup_2);
            app.ACvButton.Text = 'ACv:';
            app.ACvPoint = uieditfield(app.ButtonGroup_2, 'numeric');
            app.ACv_extrema = uiswitch(app.ButtonGroup_2, 'slider');
            app.ACv_extrema.Items = {'Min', 'Max'};
            app.ACv_extrema.Value = 'Min';
            app.MOButton = uiradiobutton(app.ButtonGroup_2);
            app.MOButton.Text = 'MO:';
            app.MOPoint = uieditfield(app.ButtonGroup_2, 'numeric');
            app.MO_extrema = uiswitch(app.ButtonGroup_2, 'slider');
            app.MO_extrema.Items = {'Min', 'Max'};
            app.MO_extrema.Value = 'Min';
            app.BacktomenuButton = uibutton(app.ButtonGroup_2, 'push');
            app.BacktomenuButton.ButtonPushedFcn = createCallbackFcn(app, @BacktomenuButtonPushed, true);
            app.BacktomenuButton.Text = 'Back to menu';
            app.PreviousButton = uibutton(app.AnnotatingPanel, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.NextSaveButton = uibutton(app.AnnotatingPanel, 'push');
            app.NextSaveButton.ButtonPushedFcn = createCallbackFcn(app, @NextSaveButtonPushed, true);
            app.NextSaveButton.Text = 'Next + Save';
            app.Next20Button = uibutton(app.AnnotatingPanel, 'push');
            app.Next20Button.ButtonPushedFcn = createCallbackFcn(app, @Next20ButtonPushed, true);
            app.Next20Button.Text = 'Next 20';
            app.Previous20Button = uibutton(app.AnnotatingPanel, 'push');
            app.Previous20Button.ButtonPushedFcn = createCallbackFcn(app, @Previous20ButtonPushed, true);
            app.Previous20Button.Text = 'Previous 20';
            app.SliderLabel = uilabel(app.AnnotatingPanel);
            app.SliderLabel.Visible = 'off';
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.AnnotatingPanel);
            app.Slider.Limits = [0 10];
            app.Slider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [0 0.2 0.4 0.6 0.8 1 1.2 1.4 1.6 1.8 2 2.2 2.4 2.6 2.8 3 3.2 3.4 3.6 3.8 4 4.2 4.4 4.6 4.8 5 5.2 5.4 5.6 5.8 6 6.2 6.4 6.6 6.8 7 7.2 7.4 7.6 7.8 8 8.2 8.4 8.6 8.8 9 9.2 9.4 9.6 9.8 10];
            app.Slider.Visible = 'off';
            app.SubjectLabel = uilabel(app.AnnotatingPanel);
            app.SubjectLabel.Text = 'Subject #: ';
            app.BeatLabel = uilabel(app.AnnotatingPanel);
            app.BeatLabel.Text = 'Beat #: ';
            app.AnnotatorLabel = uilabel(app.AnnotatingPanel);
            app.AnnotatorLabel.Text = 'Annotator: ';
            app.JumpTo = uieditfield(app.AnnotatingPanel, 'numeric');
            app.JumptoButton = uibutton(app.AnnotatingPanel, 'push');
            app.JumptoButton.ButtonPushedFcn = createCallbackFcn(app, @JumptoButtonPushed, true);
            app.JumptoButton.Text = 'Jump to';
            app.AO1 = uieditfield(app.AnnotatingPanel, 'numeric');
            app.AO1.Visible = 'off';
            app.AO2 = uieditfield(app.AnnotatingPanel, 'numeric');
            app.AO2.Visible = 'off';
            app.HeatmapBoundApproximationsLabel = uilabel(app.AnnotatingPanel);
            app.HeatmapBoundApproximationsLabel.Text = 'AOLB, AOUB, ACLB, ACUB';
            app.AOLB = uilabel(app.AnnotatingPanel);
            app.AOLB.Text = 'aolb';
            app.AOUB = uilabel(app.AnnotatingPanel);
            app.AOUB.Text = 'aoub';
            app.ACLB = uilabel(app.AnnotatingPanel);
            app.ACLB.Text = 'aclb';
            app.ACUB = uilabel(app.AnnotatingPanel);
            app.ACUB.Text = 'acup';
            app.ShowentireHeatMapButton = uibutton(app.AnnotatingPanel, 'push');
            app.ShowentireHeatMapButton.ButtonPushedFcn = createCallbackFcn(app, @ShowentireHeatMapButtonPushed, true);
            app.ShowentireHeatMapButton.Text = 'Show entire HeatMap';
            app.AC1 = uieditfield(app.AnnotatingPanel, 'numeric');
            app.AC1.Visible = 'off';
            app.AC2 = uieditfield(app.AnnotatingPanel, 'numeric');
            app.AC2.Visible = 'off';
            app.LowestUnannotatedBeatLabel = uilabel(app.AnnotatingPanel);
            app.LowestUnannotatedBeatLabel.Text = 'Lowest Unannotated Beat: ';
            app.ButtonGroup_3 = uibuttongroup(app.AnnotatingPanel);
            app.ButtonGroup_3.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_3SelectionChanged, true);
            app.NoRatingButton_2 = uitogglebutton(app.ButtonGroup_3);
            app.NoRatingButton_2.Text = 'No Rating';
            app.NoRatingButton_2.Value = true;
            app.BadButton = uitogglebutton(app.ButtonGroup_3);
            app.BadButton.Text = 'Bad';
            app.AverageButton = uitogglebutton(app.ButtonGroup_3);
            app.AverageButton.Text = 'Average';
            app.GoodButton = uitogglebutton(app.ButtonGroup_3);
            app.GoodButton.Text = 'Good';
            app.XEditFieldLabel = uilabel(app.AnnotatingPanel);
            app.XEditFieldLabel.Text = 'X=';
            app.XEditField = uieditfield(app.AnnotatingPanel, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.Clear_Annotations = uibutton(app.AnnotatingPanel, 'push');
            app.Clear_Annotations.ButtonPushedFcn = createCallbackFcn(app, @Clear_AnnotationsButtonPushed, true);
            app.Clear_Annotations.Text = 'Clear Annotations';
            app.ShiftedCheckBox = uicheckbox(app.AnnotatingPanel);
            app.ShiftedCheckBox.Text = 'Shifted';
            app.MainMenuPanel = uipanel(app.AnnotatingPanel);
            app.MainMenuPanel.Title = 'Main Menu';
            app.StartAnnotatingButton = uibutton(app.MainMenuPanel, 'push');
            app.StartAnnotatingButton.ButtonPushedFcn = createCallbackFcn(app, @StartAnnotatingButtonPushed, true);
            app.StartAnnotatingButton.Enable = 'off';
            app.StartAnnotatingButton.Text = 'Start Annotating';
            app.AnnotatorFirstNameEditFieldLabel = uilabel(app.MainMenuPanel);
            app.AnnotatorFirstNameEditFieldLabel.Text = 'Annotator First Name:';
            app.AnnotatorFirstNameEditField = uieditfield(app.MainMenuPanel, 'text');
            app.AnnotatorFirstNameEditField.ValueChangedFcn = createCallbackFcn(app, @AnnotatorFirstNameEditFieldValueChanged, true);
            app.SubjectLabel_2 = uilabel(app.MainMenuPanel);
            app.SubjectLabel_2.Text = 'Subject #: ';
            app.SubjectEditField = uieditfield(app.MainMenuPanel, 'numeric');
            app.SubjectEditField.ValueChangedFcn = createCallbackFcn(app, @SubjectEditFieldValueChanged, true);
            app.FileNotFoundErrorLabel = uilabel(app.MainMenuPanel);
            app.FileNotFoundErrorLabel.Text = '';
            app.WhatisthesamplingrateHzLabel = uilabel(app.MainMenuPanel);
            app.WhatisthesamplingrateHzLabel.Text = 'What is the sampling rate (Hz)?';
            app.SamplingRate = uieditfield(app.MainMenuPanel, 'numeric');
            app.SamplingRate.ValueChangedFcn = createCallbackFcn(app, @SamplingRateValueChanged, true);
            app.TypeoffiletoaccessButtonGroup = uibuttongroup(app.MainMenuPanel);
            app.TypeoffiletoaccessButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TypeoffiletoaccessButtonGroupSelectionChanged, true);
            app.TypeoffiletoaccessButtonGroup.Title = 'Type of file to access';
            app.StartingNewButton = uiradiobutton(app.TypeoffiletoaccessButtonGroup);
            app.StartingNewButton.Text = 'Starting New';
            app.StartingNewButton.Value = true;
            app.InProgressButton = uiradiobutton(app.TypeoffiletoaccessButtonGroup);
            app.InProgressButton.Text = 'In Progress';
            app.FinishedButton = uiradiobutton(app.TypeoffiletoaccessButtonGroup);
            app.FinishedButton.Text = 'Finished';
            app.FileAlreadyInProgressWarning = uilabel(app.MainMenuPanel);
            app.FileAlreadyInProgressWarning.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnnotationGui_v11_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end